classdef FilePreviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ColormapOptionsButtonGroup  matlab.ui.container.ButtonGroup
        HotButton                   matlab.ui.control.RadioButton
        JetButton                   matlab.ui.control.RadioButton
        CoolButton                  matlab.ui.control.RadioButton
        HSVButton                   matlab.ui.control.RadioButton
        TurboButton                 matlab.ui.control.RadioButton
        ParulaButton                matlab.ui.control.RadioButton
        ofColorsSlider              matlab.ui.control.Slider
        ofColorsSliderLabel         matlab.ui.control.Label
        ColormapLabel               matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        FramesEditField             matlab.ui.control.NumericEditField
        FramesSlider                matlab.ui.control.Slider
        FramesSliderLabel           matlab.ui.control.Label
        SelectDataButton            matlab.ui.control.Button
        DataUIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp; % Parent app
        filePath;
        Stack; % Image stack
        isColormapOn;
        height = 100;
        width = 900;
    end
    
    methods (Access = public)
        
        % Calulates the colormapping for the given frame & value
        function file = colorMapChange(app, frame, val)
            if (app.HotButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), hot(val));
            elseif (app.JetButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), jet(val));
            elseif (app.CoolButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), cool(val));
            elseif (app.HSVButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), hsv(val));
            elseif (app.TurboButton.Value)
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), turbo(val));
            else
                file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,frame))), parula(val));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
            app.CallingApp = STICSgui1;
            axis(app.DataUIAxes, 'equal');
            app.DataUIAxes.XColor = 'w';
            app.DataUIAxes.YColor = 'w';
            app.isColormapOn = app.Switch.Value;
            disableDefaultInteractivity(app.DataUIAxes);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                return
            end
            
            app.filePath = pathName;
            ch1name=fileName(1:end-4);
            data = [app.filePath ch1name '.tif'];
            
            % Add path to code from parent folder
            addpath('current/STICS/');
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.Stack = readFileToStack(data);
                catch Error
                    % Select another file
                    [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            app.FramesSlider.Limits = [1, size(app.Stack, 3)];
            
            %app.ofColorsSlider.Limits = [1, max(app.Stack(:,:,1), [], 'all')];
            app.ofColorsSlider.Limits = [1, 255];
            
            % Convert image to uint8
            %index = 1:size(app.Stack, 3);
            %app.Stack(:,:,index) = im2uint8(app.Stack(:,:,index));
            
            %app.Stack(:,:,1)
            
            %uint8(255 * mat2gray(app.Stack(:,:,1)))
            %rescale(app.Stack(:,:,ceil(app.FramesSlider.Value)))
            %ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), jet(app.ofColorsSlider.Value))
            
            %im2uint8(app.Stack(:,:,1))
            
            % Set image as first frame once file loaded
            file = app.Stack(:,:,1);
            
            %{
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,1);
                %file = uint8(255 * mat2gray(app.Stack(:,:,1)));
            else
                %file = ind2rgb(app.Stack(:,:,1), jet(app.ofColorsSlider.Value));
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), jet(app.ofColorsSlider.Value));
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,1))), cool(app.ofColorsSlider.Value));
                %file = ind2rgb(rescale(app.Stack(:,:,1)), jet(app.ofColorsSlider.Value));
                %file = imagesc(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))));
                %file = ind2rgb(app.Stack(:,:,1), cool(app.ofColorsSlider.Value));
            end
            %}
            
            
            imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            
            % Check if file valid
            if (isValid)
                % Enable adjustable fields
                app.SelectDataButton.BackgroundColor = [0.33 0.77 0.01];
                app.FramesSlider.Enable = 'On';
                app.FramesSliderLabel.Enable = 'On';
                app.FramesEditField.Enable = 'On';
                app.ofColorsSlider.Enable = 'On';
                app.ofColorsSliderLabel.Enable = 'On';
                app.Switch.Enable = 'On';
                app.ColormapOptionsButtonGroup.Enable = 'On';
                
            % File invalid
            else
                % Disable adjustable fields
                app.SelectDataButton.BackgroundColor = [1.0 0.0 0.0];
                app.FramesSlider.Enable = 'Off';
                app.FramesSliderLabel.Enable = 'Off';
                app.FramesEditField.Enable = 'Off';
                app.ofColorsSlider.Enable = 'Off';
                app.ofColorsSliderLabel.Enable = 'Off';
                app.Switch.Enable = 'Off';
                app.ColormapOptionsButtonGroup.Enable = 'Off';
            end
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            % Force value to round up
            changingValue = ceil(event.Value);
            
            % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,changingValue);
            % Colormap on
            else
                %file = ind2rgb(app.Stack(:,:,changingValue), jet(app.ofColorsSlider.Value));
                %file = uint8(255 * mat2gray(app.Stack(:,:,changingValue)));
                file = colorMapChange(app, changingValue, ceil(app.ofColorsSlider.Value));
                
                %{
                if (app.HotButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), hot(ceil(app.ofColorsSlider.Value)));
                elseif (app.JetButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), jet(ceil(app.ofColorsSlider.Value)));
                elseif (app.CoolButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), cool(ceil(app.ofColorsSlider.Value)));
                elseif (app.HSVButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), hsv(ceil(app.ofColorsSlider.Value)));
                elseif (app.TurboButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), turbo(ceil(app.ofColorsSlider.Value)));
                else
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), parula(ceil(app.ofColorsSlider.Value)));
                end
                %}
                
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,changingValue))), cool(ceil(app.ofColorsSlider.Value)));
            end
            
            imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            % imshow(app.Stack(:,:,changingValue), [100, 1000], 'Parent', app.DataUIAxes);
            % hold on;
            %{
            rectangle(app.DataUIAxes, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.DataUIAxes, 'Position', [((app.XIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               ((app.YIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               app.Opt.ROIsize, app.Opt.ROIsize]);
            %}
            
            % Adjust FramesEditField
            if (app.FramesEditField.Value ~= changingValue)
                app.FramesEditField.Value = changingValue;
            end
            
        end

        % Value changed function: FramesEditField
        function FramesEditFieldValueChanged(app, event)
            value = ceil(app.FramesEditField.Value);
            
            % Check if colormap off
            if (strcmp(app.isColormapOn, 'Off'))
                file = app.Stack(:,:,value);
            % Colormap on
            else
                %file = ind2rgb(app.Stack(:,:,value), jet(app.ofColorsSlider.Value));
                %file = uint8(255 * mat2gray(app.Stack(:,:,value)));
                
                file = colorMapChange(app, value, ceil(app.ofColorsSlider.Value));
                
                %{
                if (app.HotButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), hot(ceil(app.ofColorsSlider.Value)));
                elseif (app.JetButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), jet(ceil(app.ofColorsSlider.Value)));
                elseif (app.CoolButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), cool(ceil(app.ofColorsSlider.Value)));
                elseif (app.HSVButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), hsv(ceil(app.ofColorsSlider.Value)));
                elseif (app.TurboButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), turbo(ceil(app.ofColorsSlider.Value)));
                else
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), parula(ceil(app.ofColorsSlider.Value)));
                end
                %}
                
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,value))), cool(ceil(app.ofColorsSlider.Value)));
            end
            
            imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            
            % Adjust slider value
            if (app.FramesSlider.Value ~= value)
                app.FramesSlider.Value = value;
            end
        end

        % Value changing function: ofColorsSlider
        function ofColorsSliderValueChanging(app, event)
            changingValue = ceil(event.Value);
            
            % Show changing colormap values if bool = true
            if (strcmp(app.isColormapOn, 'On'))
                %file = ind2rgb(app.Stack(:,:,app.FramesSlider.Value), jet(changingValue));
                
                file = colorMapChange(app, ceil(app.FramesSlider.Value), changingValue);
                
                %{
                if (app.HotButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hot(changingValue));
                elseif (app.JetButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), jet(changingValue));
                elseif (app.CoolButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(changingValue));
                elseif (app.HSVButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hsv(changingValue));
                elseif (app.TurboButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), turbo(changingValue));
                else
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), parula(changingValue));
                end
                %}
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(changingValue));
                %file = ind2rgb(app.Stack(:,:,ceil(app.FramesSlider.Value)), cool(changingValue));
                imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.isColormapOn = value;
            
            % Check if switch flipped to on
            if (strcmp(value, 'On'))
                %imshow(ind2rgb(app.Stack(:,:,ceil(app.FramesSlider.Value)), jet(app.ofColorsSlider.Value)), [100, 900], 'Parent', app.DataUIAxes);
                %imshow(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), [100, 900], 'Parent', app.DataUIAxes);
                %imshow(ind2rgb(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))), jet(app.ofColorsSlider.Value)), [100, 900], 'Parent', app.DataUIAxes);
                %file = imagesc(rescale(app.Stack(:,:,ceil(app.FramesSlider.Value))), 'Visible', 'off');
                %imshow(file, [100, 900], 'Parent', app.DataUIAxes);
                %file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(ceil(app.ofColorsSlider.Value)));
                
                file = colorMapChange(app, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
                
                %{
                if (app.HotButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hot(ceil(app.ofColorsSlider.Value)));
                elseif (app.JetButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), jet(ceil(app.ofColorsSlider.Value)));
                elseif (app.CoolButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(ceil(app.ofColorsSlider.Value)));
                elseif (app.HSVButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hsv(ceil(app.ofColorsSlider.Value)));
                elseif (app.TurboButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), turbo(ceil(app.ofColorsSlider.Value)));
                else
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), parula(ceil(app.ofColorsSlider.Value)));
                end
                %}
                
                imshow(file, [app.height, app.width], 'Parent', app.DataUIAxes);
            % Switch off
            else
                imshow(app.Stack(:,:,ceil(app.FramesSlider.Value)), [app.height, app.width], 'Parent', app.DataUIAxes);
            end
            
        end

        % Selection changed function: ColormapOptionsButtonGroup
        function ColormapOptionsButtonGroupSelectionChanged(app, event)
            % Check if colormap on when new option clicked
            if (strcmp(app.isColormapOn, 'On'))
                file = colorMapChange(app, ceil(app.FramesSlider.Value), ceil(app.ofColorsSlider.Value));
                
                %{
                if (app.HotButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hot(ceil(app.ofColorsSlider.Value)));
                elseif (app.JetButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), jet(ceil(app.ofColorsSlider.Value)));
                elseif (app.CoolButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), cool(ceil(app.ofColorsSlider.Value)));
                elseif (app.HSVButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), hsv(ceil(app.ofColorsSlider.Value)));
                elseif (app.TurboButton.Value)
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), turbo(ceil(app.ofColorsSlider.Value)));
                else
                    file = ind2rgb(uint8(255 * mat2gray(app.Stack(:,:,ceil(app.FramesSlider.Value)))), parula(ceil(app.ofColorsSlider.Value)));
                end
                %}
                    
                imshow(file, [100, 900], 'Parent', app.DataUIAxes);
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DataUIAxes = uiaxes(app.UIFigure);
            app.DataUIAxes.XTick = [];
            app.DataUIAxes.YTick = [];
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Enable = 'off';
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.Enable = 'off';
            app.FramesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FramesEditField.ValueChangedFcn = createCallbackFcn(app, @FramesEditFieldValueChanged, true);
            app.FramesEditField.Enable = 'off';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.ColormapLabel = uilabel(app.UIFigure);
            app.ColormapLabel.Text = 'Colormap';
            app.ofColorsSliderLabel = uilabel(app.UIFigure);
            app.ofColorsSliderLabel.Enable = 'off';
            app.ofColorsSliderLabel.Text = '# of Colors';
            app.ofColorsSlider = uislider(app.UIFigure);
            app.ofColorsSlider.Limits = [1 255];
            app.ofColorsSlider.ValueChangingFcn = createCallbackFcn(app, @ofColorsSliderValueChanging, true);
            app.ofColorsSlider.Enable = 'off';
            app.ofColorsSlider.Value = 255;
            app.ColormapOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.ColormapOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapOptionsButtonGroupSelectionChanged, true);
            app.ColormapOptionsButtonGroup.Enable = 'off';
            app.ColormapOptionsButtonGroup.Title = 'Colormap Options';
            app.ParulaButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.ParulaButton.Text = 'Parula';
            app.ParulaButton.Value = true;
            app.TurboButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.TurboButton.Text = 'Turbo';
            app.HSVButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.HSVButton.Text = 'HSV';
            app.CoolButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.CoolButton.Text = 'Cool';
            app.JetButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.JetButton.Text = 'Jet';
            app.HotButton = uiradiobutton(app.ColormapOptionsButtonGroup);
            app.HotButton.Text = 'Hot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilePreviewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end