classdef ICS_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        MessageLabel          matlab.ui.control.Label
        UpdatePreviewButton   matlab.ui.control.Button
        BrowseButton          matlab.ui.control.Button
        SelectButton          matlab.ui.control.Button
        InfoontheTOIFOVandvectorpositionLabel  matlab.ui.control.Label
        ViewmodeSwitch        matlab.ui.control.Switch
        ViewmodeLabel         matlab.ui.control.Label
        UIndexSlider          matlab.ui.control.Slider
        UIndexSliderLabel     matlab.ui.control.Label
        YIndexSlider          matlab.ui.control.Slider
        YIndexSliderLabel     matlab.ui.control.Label
        XIndexSlider          matlab.ui.control.Slider
        XIndexSliderLabel     matlab.ui.control.Label
        FrameSlider           matlab.ui.control.Slider
        FrameSliderLabel      matlab.ui.control.Label
        Preview               matlab.ui.control.UIAxes
        CenterPanel           matlab.ui.container.Panel
        TauSlider             matlab.ui.control.Slider
        TauSliderLabel        matlab.ui.control.Label
        ICSExplorerLabel      matlab.ui.control.Label
        FitmodeDropDown       matlab.ui.control.DropDown
        FitmodeDropDownLabel  matlab.ui.control.Label
        FitstatisticsGaussianpropertiesandgoodnessoffitLabel  matlab.ui.control.Label
        ICF3D                 matlab.ui.control.UIAxes
        RightPanel            matlab.ui.container.Panel
        Residuals             matlab.ui.control.UIAxes
        ICF2D                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Opt
        PosX
        PosY
        PosT
        Stack
        VelMap
        VelMapPath % Path to the velocity map
        CallingApp;
    end
    
    properties (Access = private)
        CurrentFrame
        CurrentPosX
        CurrentPosY
        MinFrame % Frame number of data that corresponds to the first vector field frame
        MaxFrame % Frame number of data that corresponds to the last vector field frame
        StackFileName
        StackPath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, STICSgui1)
            app.CallingApp = STICSgui1;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [VelMapFileName, app.VelMapPath] = uigetfile('*.mat', 'Select the Velocity Map');
            
            % Check if cancel was clicked
            if (VelMapFileName == 0)
                return;
            end

            
            %{
            file = fullfile(app.VelMapPath, VelMapFileName);
            vars = load(file, '-mat');
            
            app.BrowseButton.BackgroundColor = [0.33 0.77 0.01];
            
            app.VelMap = vars.velocityMap;
            app.PosX = vars.position_x;
            app.PosY = vars.position_y;
            app.PosT = vars.position_t;
            app.Opt = vars.opt;
            %}
            
            %app.PosX
            %app.PosY
            
            load([app.VelMapPath, VelMapFileName], '-mat');
            app.BrowseButton.BackgroundColor = [0.33 0.77 0.01];
            
            app.VelMap = velocityMap;
            app.PosX = position_x;
            app.PosY = position_y;
            app.PosT = position_t;
            app.Opt = opt;
            
            try
                app.Stack = readFileToStack(app.Opt.filePath{1,1});
                %app.Stack = readFileToStack();
            catch ME
                [app.StackFileName, app.StackPath] = uigetfile('*.tif', 'Select Stack File');
                app.Stack = readFileToStack([app.StackPath, app.StackFileName]);
            end
            
            %app.MinFrame = floor(app.Opt.TOIsize/2) + mod(app.Opt.TOIsize,2);
            %app.MaxFrame = size(app.Stack, 3) - app.Opt.TOIsize + 1;
            
            app.MinFrame = ceil(app.Opt.TOIsize/2);
            app.MaxFrame = size(app.Stack, 3) - floor(app.Opt.TOIsize/2);
            
            app.MinFrame
            app.MaxFrame
            
            app.FrameSlider.Limits = [app.MinFrame, app.MaxFrame];
            app.XIndexSlider.Limits = [1, size(app.PosX, 1)];
            app.YIndexSlider.Limits = [1, size(app.PosY, 2)];
            app.UIndexSlider.Limits = [1, size(app.PosX, 1)*size(app.PosY, 2)];
            app.TauSlider.Limits = [0, app.Opt.TOIsize];
            
            app.CurrentPosX = app.PosX(app.XIndexSlider.Value, 1);
            app.CurrentPosY = app.PosY(1, app.YIndexSlider.Value);
            
            
            imshow(app.Stack(:,:,app.FrameSlider.Value), [100, 1000], 'Parent', app.Preview);
%             hold on;
            rectangle(app.Preview, 'Position', [(1+app.Opt.ROIsize)/2, (1+app.Opt.ROIsize)/2,...
                size(app.Stack,1)-app.Opt.ROIsize, size(app.Stack,1)-app.Opt.ROIsize],...
                'FaceColor','none', 'LineStyle','--');
            rectangle(app.Preview, 'Position', [((app.XIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               ((app.YIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
               app.Opt.ROIsize, app.Opt.ROIsize]);        
%             plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y', 'LineWidth', 1);           
%             plot(app.Preview, app.PosX, app.PosY,'.r'); 


            app.UIndexSlider.Enable = 'On';
            app.UIndexSliderLabel.Enable = 'On';
            app.YIndexSlider.Enable = 'On';
            app.YIndexSliderLabel.Enable = 'On';
            app.XIndexSlider.Enable = 'On';
            app.XIndexSliderLabel.Enable = 'On';
            app.FrameSlider.Enable = 'On';
            app.FrameSliderLabel.Enable = 'On';


        end

        % Button down function: CenterPanel
        function CenterPanelButtonDown(app, event)
            
        end

        % Callback function: UIndexSlider, UpdatePreviewButton
        function UpdatePreviewButtonPushed(app, event)
            %app.UIndexSlider.Value = size(app.PosX,1)*app.YIndexSlider.Value + app.XIndexSlider.Value;
            
            %rectangle(app.Preview, 'Position', [((app.XIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
            %   ((app.YIndexSlider.Value-1)*app.Opt.ROIshift)+1/2,...
            %   app.Opt.ROIsize, app.Opt.ROIsize]); hold on
            %plot(app.Preview, app.CurrentPosX, app.CurrentPosY,'+y'); hold off
        end

        % Value changing function: UIndexSlider
        function UIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            
            y_val = double(idivide(int16(changingValue), int16(size(app.PosY,2))) + 1)
            x_val = double(mod(changingValue, int16(size(app.PosY,2))))
            
            %mod(changingValue,app.YIndexSlider.Value)
            
            if (y_val > app.YIndexSlider.Limits(2))
                return;
            end
            
            app.YIndexSlider.Value = y_val;
            app.XIndexSlider.Value = x_val;
            
            %app.YIndexSlider.Value = idivide(int16(changingValue),int16(size(app.PosY,2))) + 1;
            %app.XIndexSlider.Value = mod(changingValue,app.YIndexSlider.Value);
        end

        % Value changing function: XIndexSlider
        function XIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*app.YIndexSlider.Value + changingValue;
        end

        % Value changing function: YIndexSlider
        function YIndexSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIndexSlider.Value = size(app.PosX,1)*changingValue + app.XIndexSlider.Value;
        end

        % Value changed function: XIndexSlider
        function XIndexSliderValueChanged(app, event)
            value = app.XIndexSlider.Value;
            %app.CurrentPosX = app.PosX(value, 1);
            app.CurrentPosX = value;
        end

        % Value changed function: YIndexSlider
        function YIndexSliderValueChanged(app, event)
            value = app.YIndexSlider.Value;
            %app.CurrentPosY = app.PosY(1, value);
            app.CurrentPosY = value;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = app.FrameSlider.Value;
            app.CurrentFrame = value;
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {771, 771, 771};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {771, 771};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x', 329};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Preview = uiaxes(app.LeftPanel);
            title(app.Preview, 'TOIFOV location')
            xlabel(app.Preview, 'X')
            ylabel(app.Preview, 'Y')
            zlabel(app.Preview, 'Z')
            app.FrameSliderLabel = uilabel(app.LeftPanel);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.LeftPanel);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.Enable = 'off';
            app.XIndexSliderLabel = uilabel(app.LeftPanel);
            app.XIndexSliderLabel.Enable = 'off';
            app.XIndexSliderLabel.Text = 'X Index';
            app.XIndexSlider = uislider(app.LeftPanel);
            app.XIndexSlider.ValueChangedFcn = createCallbackFcn(app, @XIndexSliderValueChanged, true);
            app.XIndexSlider.ValueChangingFcn = createCallbackFcn(app, @XIndexSliderValueChanging, true);
            app.XIndexSlider.Enable = 'off';
            app.YIndexSliderLabel = uilabel(app.LeftPanel);
            app.YIndexSliderLabel.Enable = 'off';
            app.YIndexSliderLabel.Text = 'Y index';
            app.YIndexSlider = uislider(app.LeftPanel);
            app.YIndexSlider.ValueChangedFcn = createCallbackFcn(app, @YIndexSliderValueChanged, true);
            app.YIndexSlider.ValueChangingFcn = createCallbackFcn(app, @YIndexSliderValueChanging, true);
            app.YIndexSlider.Enable = 'off';
            app.UIndexSliderLabel = uilabel(app.LeftPanel);
            app.UIndexSliderLabel.Enable = 'off';
            app.UIndexSliderLabel.Text = 'U index';
            app.UIndexSlider = uislider(app.LeftPanel);
            app.UIndexSlider.ValueChangedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UIndexSlider.ValueChangingFcn = createCallbackFcn(app, @UIndexSliderValueChanging, true);
            app.UIndexSlider.Enable = 'off';
            app.ViewmodeLabel = uilabel(app.LeftPanel);
            app.ViewmodeLabel.Text = 'View mode:';
            app.ViewmodeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ViewmodeSwitch.Items = {'2D', '3D'};
            app.ViewmodeSwitch.Value = '2D';
            app.InfoontheTOIFOVandvectorpositionLabel = uilabel(app.LeftPanel);
            app.InfoontheTOIFOVandvectorpositionLabel.Text = 'Info on the TOIFOV and vector position';
            app.SelectButton = uibutton(app.LeftPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UpdatePreviewButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePreviewButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePreviewButtonPushed, true);
            app.UpdatePreviewButton.Text = 'UpdatePreview';
            app.MessageLabel = uilabel(app.LeftPanel);
            app.MessageLabel.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.ButtonDownFcn = createCallbackFcn(app, @CenterPanelButtonDown, true);
            app.ICF3D = uiaxes(app.CenterPanel);
            title(app.ICF3D, '3D surface plot')
            xlabel(app.ICF3D, 'X')
            ylabel(app.ICF3D, 'Y')
            zlabel(app.ICF3D, 'Z')
            app.FitstatisticsGaussianpropertiesandgoodnessoffitLabel = uilabel(app.CenterPanel);
            app.FitstatisticsGaussianpropertiesandgoodnessoffitLabel.Text = 'Fit statistics (Gaussian properties and goodness of fit)';
            app.FitmodeDropDownLabel = uilabel(app.CenterPanel);
            app.FitmodeDropDownLabel.Text = 'Fit mode';
            app.FitmodeDropDown = uidropdown(app.CenterPanel);
            app.FitmodeDropDown.Items = {'Symmetrical Gaussian', 'Asymmetrical Gaussian', 'Rotated Elliptical Gaussian'};
            app.FitmodeDropDown.Value = 'Symmetrical Gaussian';
            app.ICSExplorerLabel = uilabel(app.CenterPanel);
            app.ICSExplorerLabel.Text = 'ICS Explorer';
            app.TauSliderLabel = uilabel(app.CenterPanel);
            app.TauSliderLabel.Text = 'Tau';
            app.TauSlider = uislider(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.ICF2D = uiaxes(app.RightPanel);
            title(app.ICF2D, {'2D ICF view and lateral projections'; ''})
            xlabel(app.ICF2D, 'X')
            ylabel(app.ICF2D, 'Y')
            zlabel(app.ICF2D, 'Z')
            app.Residuals = uiaxes(app.RightPanel);
            title(app.Residuals, 'Residuals')
            xlabel(app.Residuals, 'X')
            ylabel(app.Residuals, 'Y')
            zlabel(app.Residuals, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ICS_Explorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end