classdef STICSgui1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        File                          matlab.ui.container.Menu
        LoadDataMenu                  matlab.ui.container.Menu
        ExitProgramMenu               matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        ProfileMenu                   matlab.ui.container.Menu
        WorkspaceMenu                 matlab.ui.container.Menu
        ThemeMenu                     matlab.ui.container.Menu
        OriginalMenu                  matlab.ui.container.Menu
        LightModeMenu                 matlab.ui.container.Menu
        DarkModeMenu                  matlab.ui.container.Menu
        NightShiftMenu                matlab.ui.container.Menu
        RandomMenu                    matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        TutorialMenu                  matlab.ui.container.Menu
        CreditsMenu                   matlab.ui.container.Menu
        LicenseMenu                   matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        UpdatePushTool                matlab.ui.container.toolbar.PushTool
        SyncPushTool                  matlab.ui.container.toolbar.PushTool
        RestartPushTool               matlab.ui.container.toolbar.PushTool
        mainTabGroup                  matlab.ui.container.TabGroup
        WelcomeTab                    matlab.ui.container.Tab
        welcomeNextButton             matlab.ui.control.Button
        ICSExplorerButton             matlab.ui.control.Button
        DataViewerButton              matlab.ui.control.Button
        JumpLabel                     matlab.ui.control.Label
        STICSButton                   matlab.ui.control.Button
        EachcorrelationspectroscopyalgorithmcontainsLabel  matlab.ui.control.Label
        UsageLabel                    matlab.ui.control.Label
        instructionsInfo              matlab.ui.control.Label
        instructionsTitle             matlab.ui.control.Label
        StartTab                      matlab.ui.container.Tab
        SaveButton                    matlab.ui.control.Button
        NewButton_2                   matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        CurrentWorkflowListBoxLabel   matlab.ui.control.Label
        CurrentWorkflowListBox        matlab.ui.control.ListBox
        Tree                          matlab.ui.container.Tree
        PresetsNode                   matlab.ui.container.TreeNode
        OneChannelNode                matlab.ui.container.TreeNode
        ICSNode                       matlab.ui.container.TreeNode
        TICSNode                      matlab.ui.container.TreeNode
        STICSNode                     matlab.ui.container.TreeNode
        TwoChannelsNode               matlab.ui.container.TreeNode
        ICCSNode                      matlab.ui.container.TreeNode
        TICCSNode                     matlab.ui.container.TreeNode
        STICCSNode                    matlab.ui.container.TreeNode
        ThreeChannelsNode             matlab.ui.container.TreeNode
        FRETSTICSNode                 matlab.ui.container.TreeNode
        CustomNode                    matlab.ui.container.TreeNode
        RadialSTICSNode               matlab.ui.container.TreeNode
        Tree2                         matlab.ui.container.CheckBoxTree
        LoadingNode                   matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        PreprocessingNode             matlab.ui.container.TreeNode
        SetupNode                     matlab.ui.container.TreeNode
        RunNode                       matlab.ui.container.TreeNode
        AnalysisNode                  matlab.ui.container.TreeNode
        ExportNode                    matlab.ui.container.TreeNode
        AvailableStepsLabel           matlab.ui.control.Label
        AvailableWorkflowsLabel       matlab.ui.control.Label
        DeleteButton                  matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        DoneButton                    matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        startBackButton               matlab.ui.control.Button
        startNextButton               matlab.ui.control.Button
        PapersButton                  matlab.ui.control.Button
        DocsButton                    matlab.ui.control.Button
        DataTab                       matlab.ui.container.Tab
        dataRemoveSelectedButton      matlab.ui.control.Button
        dataMessageLabel              matlab.ui.control.Label
        dataNextButton                matlab.ui.control.Button
        dataBackButton                matlab.ui.control.Button
        dataLaunchStackViewerButton   matlab.ui.control.Button
        dataClearMetadataButton       matlab.ui.control.Button
        dataMetadataLabel             matlab.ui.control.Label
        dataMetadataUITable           matlab.ui.control.Table
        dataClearTableButton          matlab.ui.control.Button
        dataClearCurrentButton        matlab.ui.control.Button
        dataAddtoTableButton          matlab.ui.control.Button
        dataSelectedFilesLabel        matlab.ui.control.Label
        dataFileUITable               matlab.ui.control.Table
        dataSelectFileLamp            matlab.ui.control.Lamp
        dataSelectFileButton          matlab.ui.control.Button
        PrepTab                       matlab.ui.container.Tab
        prepNextButton                matlab.ui.control.Button
        prepBackButton                matlab.ui.control.Button
        prepTabGroup                  matlab.ui.container.TabGroup
        LoadTab                       matlab.ui.container.Tab
        RegisterTab                   matlab.ui.container.Tab
        BleachTab                     matlab.ui.container.Tab
        CropTab                       matlab.ui.container.Tab
        BackgroundTab                 matlab.ui.container.Tab
        ImmobileTab                   matlab.ui.container.Tab
        FFTTab                        matlab.ui.container.Tab
        SetupTab                      matlab.ui.container.Tab
        setupLaunchStackViewerButton  matlab.ui.control.Button
        setupAddtoTableButton         matlab.ui.control.Button
        setupProtocolDropDown         matlab.ui.control.DropDown
        ProtocolDropDownLabel         matlab.ui.control.Label
        setupBatchEditField           matlab.ui.control.NumericEditField
        BatchEditFieldLabel           matlab.ui.control.Label
        setupMessageLabel             matlab.ui.control.Label
        setupChannel2DropDown         matlab.ui.control.DropDown
        Channel2Label                 matlab.ui.control.Label
        setupChannel1DropDown         matlab.ui.control.DropDown
        Channel1Label                 matlab.ui.control.Label
        setupRemoveSelectedButton     matlab.ui.control.Button
        setupNextButton               matlab.ui.control.Button
        setupBackButton               matlab.ui.control.Button
        setupClearTableButton         matlab.ui.control.Button
        setupFitRadiusEditField       matlab.ui.control.NumericEditField
        FitRadiusEditFieldLabel       matlab.ui.control.Label
        setupFitModeDropDown          matlab.ui.control.DropDown
        FitModeDropDownLabel          matlab.ui.control.Label
        setupTOIShiftEditField        matlab.ui.control.NumericEditField
        TOIShiftEditFieldLabel        matlab.ui.control.Label
        setupTOISizeEditField         matlab.ui.control.NumericEditField
        TOISizeEditFieldLabel         matlab.ui.control.Label
        setupROIShiftEditField        matlab.ui.control.NumericEditField
        ROIShiftEditFieldLabel        matlab.ui.control.Label
        setupROISizeEditField         matlab.ui.control.NumericEditField
        ROISizeEditFieldLabel         matlab.ui.control.Label
        setupUITable                  matlab.ui.control.Table
        RunTab                        matlab.ui.container.Tab
        UITable_4                     matlab.ui.control.Table
        runQueueLabel_3               matlab.ui.control.Label
        UITable_3                     matlab.ui.control.Table
        runQueueLabel_2               matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        runQueueLabel                 matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        runNextButton                 matlab.ui.control.Button
        runBackButton                 matlab.ui.control.Button
        ResultsTab                    matlab.ui.container.Tab
        resultsBackButton             matlab.ui.control.Button
        resultsNextButton             matlab.ui.control.Button
        AnalysisTab                   matlab.ui.container.Tab
        analysisNextButton            matlab.ui.control.Button
        analysisBackButton            matlab.ui.control.Button
        ExportTab                     matlab.ui.container.Tab
        exportDoneButton              matlab.ui.control.Button
        exportBackButton              matlab.ui.control.Button
    end

    properties (Access = public)
        currTab;
        
        % Data Tab variables
        dataFileStack;
        dataFilePath;
        dataFileName;
        dataFileArray;
        dataFilePathArray;
        
        currLength;
        currLine;
        
        %~~~~~~~~~~~~~~~~~~~~%
        
        % Setup Tab Variables
        setupCurrLength;
        setupCurrLine;
        setupVars; % 2 = Batch, 3 = Protocol, 4 = C1, 5 = C2, 6 = ROIsize, 7 = ROIshift, 8 = TOIsize, 9 = TOIshift,
                   % 10 = FitMode, 11 = FitRadius, 12 = FileSelection
        
        
    end
    
    properties (Access = private)
        STICSfield_selectionApp; % STICS field selection app
        ICSExplorerApp; % ICS explorer app
        FileViewer; % File previewer app
    end
    
    methods (Access = public) 
        function resetMetaTable(app)
            app.dataMetadataUITable.Data = {'FileID', 0; 'Size(x)', 0; 'Size(y)', 0; 'Size(t)', 0;
                'Size(c)', 0; 'PixelSize', 0; 'Frame Rate', 0};
        end
        
        function clearVars(app)
            app.dataFileName = [];
            app.dataFilePath = [];
            app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
        end
        
        function changeTheme(app, theme)
            if (strcmp(theme, 'DarkMode'))
                color1Array = [0.0 0.0 0.0];
                color2Array = [1.0 1.0 1.0];
            end
            
            % Instructions Tab
            app.WelcomeTab.BackgroundColor = color1Array;
            app.instructionsTitle.FontColor = color2Array;
            app.instructionsInfo.FontColor = color2Array;
            app.UsageLabel.FontColor = color2Array;
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.FontColor = color2Array;
            app.JumpLabel.FontColor = color2Array;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('current/STICS/');
            addpath('Icons/');
            
            % Push Icon by mithun on freeicons.io
            % Sync Icon by Free Preloaders on freeicons.io
            % Restart Icon by anumithun on freeicons.io
            
            
            app.currTab = app.WelcomeTab;
            
            % Data Tab Init
            resetMetaTable(app);
            app.dataFileUITable.ColumnWidth = {400, 'auto'};
            app.dataMetadataUITable.ColumnWidth = {75, 'auto'};
            set(app.dataFileUITable, 'ColumnEditable', [false, true]);
            
            % Setup Tab Init
            app.setupUITable.ColumnWidth = {40, 60, 70, 230, 230, 60, 70, 60, 60, 100, 70, 'auto'};
            set(app.setupUITable, 'ColumnEditable', [false, false, false, false, false, false, false, false, ...
                                                     false, false, false, true]);
            
        end

        % Selection change function: mainTabGroup
        function mainTabGroupSelectionChanged(app, event)
            app.mainTabGroup.SelectedTab = app.currTab;
        end

        % Menu selected function: DarkModeMenu
        function DarkModeMenuSelected(app, event)
           changeTheme(app, 'DarkMode');
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            app.FileViewer = FilePreviewer(app, [], []);
        end

        % Button pushed function: ICSExplorerButton
        function ICSExplorerButtonPushed(app, event)
            app.ICSExplorerApp = ICS_Explorer(app);
        end

        % Menu selected function: ExitProgramMenu
        function ExitProgramMenuSelected(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Menu selected function: ProfileMenu
        function ProfileMenuSelected(app, event)
            
        end

        % Menu selected function: LoadDataMenu
        function LoadDataMenuSelected(app, event)
            
        end

        % Button pushed function: welcomeNextButton
        function welcomeNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.StartTab;
            app.currTab = app.StartTab;
        end

        % Button pushed function: startNextButton
        function startNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.DataTab;
            app.currTab = app.DataTab;
        end

        % Button pushed function: startBackButton
        function startBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.WelcomeTab;
            app.currTab = app.WelcomeTab;
        end

        % Button pushed function: dataSelectFileButton
        function dataSelectFileButtonPushed(app, event)
            app.dataSelectFileLamp.Color = [1.0 1.0 0.0];
            
            [fileName, pathName] = uigetfile('*.tif','Please select your data set');
            
            % Check if user clicked cancel
            if (fileName == 0)
                if (isempty(app.dataFilePath))
                    app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
                else
                    app.dataSelectFileLamp.Color = [0.0 1.0 0.0];
                end
                
                return
            end   
            
            app.dataFilePath = pathName;
            app.dataFileName =fileName(1:end-4);
            data = [app.dataFilePath app.dataFileName '.tif'];
            
            % Check if data is in MultiTiff files
            if (strcmp(fileName(end-2:end),'tif'))
                try
                    app.dataFileStack = readFileToStack(data);

                catch Error
                    % Select another file
                    [StackFileName, StackPath] = uigetfile('*.tif', 'Select Stack File');
                    app.dataFileStack = readFileToStack([StackPath, StackFileName]);
                end
                
                isValid = 1;
            else
                isValid = 0;
            end
            
            % Check if file valid
            if (isValid)
                app.dataSelectFileLamp.Color = [0.0 1.0 0.0];    
            % File invalid
            else
                app.dataSelectFileLamp.Color = [1.0 0.0 0.0];
            end
        end

        % Button pushed function: dataAddtoTableButton
        function dataAddtoTableButtonPushed(app, event)
            % Add row if at least one valid file loaded
            if (~isempty(app.dataFileName))
                % Get proper index value in array
                if (isempty(app.currLength))
                    app.currLength = 1;
                else
                    app.currLength = app.currLength + 1;
                end
                
                % Check if Stack not empty
                if (~isempty(app.dataFileStack))
                    app.dataFileArray(:,:,:,app.currLength) = app.dataFileStack;
                    app.dataFilePathArray(:,:,:,app.currLength) = app.dataFilePath;
                end
                
                data = get(app.dataFileUITable, 'Data');
                
                if (isempty(data))
                    app.dataFileUITable.Data = {app.dataFileName, false};
                else
                    newRow = {app.dataFileName, false};
                    data(end + 1, :) = newRow;
                    set(app.dataFileUITable, 'Data', data);
                end
                
                clearVars(app);
            end
        end

        % Cell edit callback: dataFileUITable
        function dataFileUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.currLine))
                if (newData)
                    app.currLine = indices(1);
                    
                    data = get(app.dataFileUITable, 'Data');
                    
                    metaData = get(app.dataMetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 3)};
                    set(app.dataMetadataUITable, 'Data', metaData);
                end
            else
                if (newData)
                    data = get(app.dataFileUITable, 'Data');
                    data(app.currLine, 2) = {false};
                    set(app.dataFileUITable, 'Data', data);
                
                    app.currLine = indices(1);
                    
                    data = get(app.dataFileUITable, 'Data');
                    
                    metaData = get(app.dataMetadataUITable, 'Data');
                    metaData(1, 2) = data(app.currLine, 1);
                    metaData(2, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 2)};
                    metaData(3, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 1)};
                    metaData(4, 2) = {size(app.dataFileArray(:,:,:,app.currLine), 3)};
                    set(app.dataMetadataUITable, 'Data', metaData);
                else
                    app.currLine = [];
                    resetMetaTable(app);
                end
            end
        end

        % Button pushed function: dataClearCurrentButton
        function dataClearCurrentButtonPushed(app, event)
            clearVars(app);
            
            app.dataFileStack = [];
            app.currLength = [];
        end

        % Button pushed function: dataRemoveSelectedButton
        function dataRemoveSelectedButtonPushed(app, event)
            if (~isempty(app.currLine))
                data = get(app.dataFileUITable, 'Data');
                data(app.currLine, :) = [];
                set(app.dataFileUITable, 'Data', data);
                
                app.dataFileArray([1:app.currLine-1 app.currLine+1:end]);
                app.currLine = [];
                app.currLength = app.currLength - 1;
                
                resetMetaTable(app);
            end
        end

        % Button pushed function: dataClearTableButton
        function dataClearTableButtonPushed(app, event)
            set(app.dataFileUITable, 'Data', {})
            
            clearVars(app);
            resetMetaTable(app);
            
            app.currLength = [];
            app.currLine = [];
            app.dataFileArray = [];
            app.dataFilePathArray = [];
            app.dataFileStack = [];
        end

        % Button pushed function: dataClearMetadataButton
        function dataClearMetadataButtonPushed(app, event)
            resetMetaTable(app);
        end

        % Button pushed function: dataLaunchStackViewerButton
        function dataLaunchStackViewerButtonPushed(app, event)
            if (~isempty(app.dataFileArray))
                if (~isempty(app.currLine))
                    app.dataFileStack = app.dataFileArray(:,:,:,app.currLine);
                    
                    if (~all(app.dataFileStack))
                        app.dataFileStack = [];
                    end
                end
            end
            
            app.FileViewer = FilePreviewer(app, app.dataFileStack, []);
        end

        % Button pushed function: dataBackButton
        function dataBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.StartTab;
            app.currTab = app.StartTab;
        end

        % Button pushed function: dataNextButton
        function dataNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.PrepTab;
            app.currTab = app.PrepTab;
            
            data = get(app.dataFileUITable, 'Data');
            
            if (isempty(data))
                app.dataMessageLabel.FontColor = [1.0 0.0 0.0];
                app.dataMessageLabel.Text = 'Error: Please add at least one file';
                app.mainTabGroup.SelectedTab = app.DataTab;
                app.currTab = app.DataTab;
            else
                app.dataMessageLabel.Text = '';
                
                app.setupChannel1DropDown.Items =  data(:, 1)';
                app.setupChannel2DropDown.Items =  [{'None'}, data(:, 1)'];
            end
        end

        % Button pushed function: setupAddtoTableButton
        function setupAddtoTableButtonPushed(app, event)
            % Get proper index value in array
            if (isempty(app.setupCurrLength))
               app.setupCurrLength = 1;
            else
               app.setupCurrLength = app.setupCurrLength + 1;
            end
            
            data = get(app.setupUITable, 'Data');
                
            if (isempty(data))
                app.setupUITable.Data = {app.setupCurrLength, app.setupBatchEditField.Value, app.setupProtocolDropDown.Value,...
                   app.setupChannel1DropDown.Value, app.setupChannel2DropDown.Value, app.setupROISizeEditField.Value,...
                   app.setupROIShiftEditField.Value, app.setupTOISizeEditField.Value, app.setupTOIShiftEditField.Value,...
                   app.setupFitModeDropDown.Value, app.setupFitRadiusEditField.Value, false};
            else
                newRow = {app.setupCurrLength, app.setupBatchEditField.Value, app.setupProtocolDropDown.Value,...
                   app.setupChannel1DropDown.Value, app.setupChannel2DropDown.Value, app.setupROISizeEditField.Value,...
                   app.setupROIShiftEditField.Value, app.setupTOISizeEditField.Value, app.setupTOIShiftEditField.Value,...
                   app.setupFitModeDropDown.Value, app.setupFitRadiusEditField.Value, false};
                data(end + 1, :) = newRow;
                set(app.setupUITable, 'Data', data);
            end
        end

        % Cell edit callback: setupUITable
        function setupUITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            % Check if file line not selected
            if (isempty(app.setupCurrLine))
                if (newData)
                    app.setupCurrLine = indices(1); 
                end
            else
                if (newData)
                    data = get(app.setupUITable, 'Data');
                    data(app.setupCurrLine, 12) = {false};
                    set(app.setupUITable, 'Data', data);
                
                    app.setupCurrLine = indices(1);
                else
                    app.setupCurrLine = [];
                end
            end
        end

        % Button pushed function: setupRemoveSelectedButton
        function setupRemoveSelectedButtonPushed(app, event)
            if (~isempty(app.setupCurrLine))
                data = get(app.setupUITable, 'Data');
                data(app.setupCurrLine, :) = [];
                set(app.setupUITable, 'Data', data);
                
                %app.dataFileArray([1:app.currLine-1 app.currLine+1:end]);
                app.setupCurrLine = [];
            end
        end

        % Button pushed function: setupClearTableButton
        function setupClearTableButtonPushed(app, event)
            set(app.setupUITable, 'Data', {})
            
            app.setupCurrLength = [];          
            app.setupCurrLine = [];
        end

        % Button pushed function: setupLaunchStackViewerButton
        function setupLaunchStackViewerButtonPushed(app, event)
            if (~isempty(app.dataFileArray))
                
                %{
                if (~isempty(app.currLine))
                    app.dataFileStack = app.dataFileArray(:,:,:,app.currLine);
                    
                    if (~all(app.dataFileStack))
                        app.dataFileStack = [];
                    end
                end
                %}
            end
            
            app.FileViewer = FilePreviewer(app, app.dataFileStack, []);
        end

        % Button pushed function: setupBackButton
        function setupBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.PrepTab;
            app.currTab = app.PrepTab;
        end

        % Button pushed function: setupNextButton
        function setupNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.RunTab;
            app.currTab = app.RunTab;
        end

        % Button pushed function: prepBackButton
        function prepBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.DataTab;
            app.currTab = app.DataTab;
        end

        % Button pushed function: prepNextButton
        function prepNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.SetupTab;
            app.currTab = app.SetupTab;
        end

        % Button pushed function: runBackButton
        function runBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.SetupTab;
            app.currTab = app.SetupTab;
        end

        % Button pushed function: runNextButton
        function runNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.ResultsTab;
            app.currTab = app.ResultsTab;
        end

        % Button pushed function: resultsBackButton
        function resultsBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.RunTab;
            app.currTab = app.RunTab;
        end

        % Button pushed function: resultsNextButton
        function resultsNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.AnalysisTab;
            app.currTab = app.AnalysisTab;
        end

        % Button pushed function: analysisBackButton
        function analysisBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.ResultsTab;
            app.currTab = app.ResultsTab;
        end

        % Button pushed function: analysisNextButton
        function analysisNextButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.ExportTab;
            app.currTab = app.ExportTab;
        end

        % Button pushed function: exportBackButton
        function exportBackButtonPushed(app, event)
            app.mainTabGroup.SelectedTab = app.AnalysisTab;
            app.currTab = app.AnalysisTab;
        end

        % Button pushed function: exportDoneButton
        function exportDoneButtonPushed(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.STICSfield_selectionApp);
            delete(app.ICSExplorerApp);
            delete(app.FileViewer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.LoadDataMenu = uimenu(app.File);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataMenuSelected, true);
            app.LoadDataMenu.Separator = 'on';
            app.LoadDataMenu.Text = 'Load Data';
            app.ExitProgramMenu = uimenu(app.File);
            app.ExitProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitProgramMenuSelected, true);
            app.ExitProgramMenu.Separator = 'on';
            app.ExitProgramMenu.Text = 'Exit Program';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ProfileMenu = uimenu(app.SettingsMenu);
            app.ProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @ProfileMenuSelected, true);
            app.ProfileMenu.Separator = 'on';
            app.ProfileMenu.Text = 'Profile';
            app.WorkspaceMenu = uimenu(app.SettingsMenu);
            app.WorkspaceMenu.Separator = 'on';
            app.WorkspaceMenu.Text = 'Workspace';
            app.ThemeMenu = uimenu(app.SettingsMenu);
            app.ThemeMenu.Separator = 'on';
            app.ThemeMenu.Text = 'Theme';
            app.OriginalMenu = uimenu(app.ThemeMenu);
            app.OriginalMenu.Separator = 'on';
            app.OriginalMenu.Text = 'Original';
            app.LightModeMenu = uimenu(app.ThemeMenu);
            app.LightModeMenu.Separator = 'on';
            app.LightModeMenu.Text = 'Light Mode';
            app.DarkModeMenu = uimenu(app.ThemeMenu);
            app.DarkModeMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkModeMenuSelected, true);
            app.DarkModeMenu.Separator = 'on';
            app.DarkModeMenu.Text = 'Dark Mode';
            app.NightShiftMenu = uimenu(app.ThemeMenu);
            app.NightShiftMenu.Separator = 'on';
            app.NightShiftMenu.Text = 'Night Shift';
            app.RandomMenu = uimenu(app.ThemeMenu);
            app.RandomMenu.Separator = 'on';
            app.RandomMenu.Text = 'Random';
            app.About = uimenu(app.UIFigure);
            app.About.Text = 'About';
            app.HelpMenu = uimenu(app.About);
            app.HelpMenu.Separator = 'on';
            app.HelpMenu.Text = 'Help';
            app.TutorialMenu = uimenu(app.About);
            app.TutorialMenu.Separator = 'on';
            app.TutorialMenu.Text = 'Tutorial';
            app.CreditsMenu = uimenu(app.About);
            app.CreditsMenu.Separator = 'on';
            app.CreditsMenu.Text = 'Credits';
            app.LicenseMenu = uimenu(app.About);
            app.LicenseMenu.Separator = 'on';
            app.LicenseMenu.Text = 'License';
            app.AboutMenu = uimenu(app.About);
            app.AboutMenu.Separator = 'on';
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.UpdatePushTool = uipushtool(app.Toolbar);
            app.UpdatePushTool.Icon = 'Push_Icon.png';
            app.SyncPushTool = uipushtool(app.Toolbar);
            app.SyncPushTool.Icon = 'Sync_Icon.png';
            app.RestartPushTool = uipushtool(app.Toolbar);
            app.RestartPushTool.Icon = 'Restart_Icon.png';
            app.mainTabGroup = uitabgroup(app.UIFigure);
            app.mainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @mainTabGroupSelectionChanged, true);
            app.WelcomeTab = uitab(app.mainTabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.instructionsTitle = uilabel(app.WelcomeTab);
            app.instructionsTitle.Text = 'Instructions';
            app.instructionsInfo = uilabel(app.WelcomeTab);
            app.instructionsInfo.Text = {'This application implements the Spatio-Temporal Image Correlation Spectroscopy (STICS),  '; 'Spatio-Temporal Image Cross-Correlation Spectroscopy (STICCS), Temporal Image Correlation '; 'Spectroscopy (TICS), and Image Correlation Spectroscopy (ICS) algorithms.'; ''};
            app.UsageLabel = uilabel(app.WelcomeTab);
            app.UsageLabel.Text = 'Usage:';
            app.EachcorrelationspectroscopyalgorithmcontainsLabel = uilabel(app.WelcomeTab);
            app.EachcorrelationspectroscopyalgorithmcontainsLabel.Text = {'- Each correlation spectroscopy algorithms contain pre-set values for all the variables'; '- You can hover over the text/numeric field of each option to see a tooltip explaining how each variable'; 'works in the algorithm'};
            app.STICSButton = uibutton(app.WelcomeTab, 'push');
            app.STICSButton.Enable = 'off';
            app.STICSButton.Visible = 'off';
            app.STICSButton.Text = 'STICS';
            app.JumpLabel = uilabel(app.WelcomeTab);
            app.JumpLabel.Text = 'Quick Jump to Applications';
            app.DataViewerButton = uibutton(app.WelcomeTab, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Text = 'Data Viewer';
            app.ICSExplorerButton = uibutton(app.WelcomeTab, 'push');
            app.ICSExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @ICSExplorerButtonPushed, true);
            app.ICSExplorerButton.Text = 'ICS Explorer';
            app.welcomeNextButton = uibutton(app.WelcomeTab, 'push');
            app.welcomeNextButton.ButtonPushedFcn = createCallbackFcn(app, @welcomeNextButtonPushed, true);
            app.welcomeNextButton.Text = 'Next';
            app.StartTab = uitab(app.mainTabGroup);
            app.StartTab.Title = 'Start';
            app.DocsButton = uibutton(app.StartTab, 'push');
            app.DocsButton.Text = 'Docs';
            app.PapersButton = uibutton(app.StartTab, 'push');
            app.PapersButton.Text = 'Papers';
            app.startNextButton = uibutton(app.StartTab, 'push');
            app.startNextButton.ButtonPushedFcn = createCallbackFcn(app, @startNextButtonPushed, true);
            app.startNextButton.Text = 'Next';
            app.startBackButton = uibutton(app.StartTab, 'push');
            app.startBackButton.ButtonPushedFcn = createCallbackFcn(app, @startBackButtonPushed, true);
            app.startBackButton.Text = 'Back';
            app.RemoveButton = uibutton(app.StartTab, 'push');
            app.RemoveButton.Text = '<< Remove';
            app.DoneButton = uibutton(app.StartTab, 'push');
            app.DoneButton.Text = 'Done';
            app.AddButton = uibutton(app.StartTab, 'push');
            app.AddButton.Text = 'Add >>';
            app.SelectButton = uibutton(app.StartTab, 'push');
            app.SelectButton.Text = 'Select';
            app.DeleteButton = uibutton(app.StartTab, 'push');
            app.DeleteButton.Text = 'Delete';
            app.AvailableWorkflowsLabel = uilabel(app.StartTab);
            app.AvailableWorkflowsLabel.Text = 'Available Workflows';
            app.AvailableStepsLabel = uilabel(app.StartTab);
            app.AvailableStepsLabel.Text = 'Available Steps';
            app.Tree2 = uitree(app.StartTab, 'checkbox');
            app.LoadingNode = uitreenode(app.Tree2);
            app.LoadingNode.Text = 'Loading';
            app.Node2 = uitreenode(app.LoadingNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.LoadingNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.LoadingNode);
            app.Node4.Text = 'Node4';
            app.PreprocessingNode = uitreenode(app.Tree2);
            app.PreprocessingNode.Text = 'Preprocessing';
            app.SetupNode = uitreenode(app.Tree2);
            app.SetupNode.Text = 'Setup';
            app.RunNode = uitreenode(app.Tree2);
            app.RunNode.Text = 'Run';
            app.AnalysisNode = uitreenode(app.Tree2);
            app.AnalysisNode.Text = 'Analysis';
            app.ExportNode = uitreenode(app.Tree2);
            app.ExportNode.Text = 'Export';
            app.Tree = uitree(app.StartTab);
            app.PresetsNode = uitreenode(app.Tree);
            app.PresetsNode.Text = 'Presets';
            app.OneChannelNode = uitreenode(app.PresetsNode);
            app.OneChannelNode.Text = 'One Channel';
            app.ICSNode = uitreenode(app.OneChannelNode);
            app.ICSNode.Text = 'ICS';
            app.TICSNode = uitreenode(app.OneChannelNode);
            app.TICSNode.Text = 'TICS';
            app.STICSNode = uitreenode(app.OneChannelNode);
            app.STICSNode.Text = 'STICS';
            app.TwoChannelsNode = uitreenode(app.PresetsNode);
            app.TwoChannelsNode.Text = 'Two Channels';
            app.ICCSNode = uitreenode(app.TwoChannelsNode);
            app.ICCSNode.Text = 'ICCS';
            app.TICCSNode = uitreenode(app.TwoChannelsNode);
            app.TICCSNode.Text = 'TICCS';
            app.STICCSNode = uitreenode(app.TwoChannelsNode);
            app.STICCSNode.Text = 'STICCS';
            app.ThreeChannelsNode = uitreenode(app.PresetsNode);
            app.ThreeChannelsNode.Text = 'Three Channels';
            app.FRETSTICSNode = uitreenode(app.ThreeChannelsNode);
            app.FRETSTICSNode.Text = 'FRET-STICS';
            app.CustomNode = uitreenode(app.Tree);
            app.CustomNode.Text = 'Custom';
            app.RadialSTICSNode = uitreenode(app.CustomNode);
            app.RadialSTICSNode.Text = 'Radial STICS';
            app.CurrentWorkflowListBox = uilistbox(app.StartTab);
            app.CurrentWorkflowListBoxLabel = uilabel(app.StartTab);
            app.CurrentWorkflowListBoxLabel.Text = 'Current Workflow';
            app.LoadButton = uibutton(app.StartTab, 'push');
            app.LoadButton.Text = 'Load';
            app.NewButton_2 = uibutton(app.StartTab, 'push');
            app.NewButton_2.Text = 'New';
            app.SaveButton = uibutton(app.StartTab, 'push');
            app.SaveButton.Text = 'Save';
            app.DataTab = uitab(app.mainTabGroup);
            app.DataTab.Title = 'Data';
            app.dataSelectFileButton = uibutton(app.DataTab, 'push');
            app.dataSelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @dataSelectFileButtonPushed, true);
            app.dataSelectFileButton.Text = 'Select File';
            app.dataSelectFileLamp = uilamp(app.DataTab);
            app.dataFileUITable = uitable(app.DataTab);
            app.dataFileUITable.ColumnName = {'File'; 'Select File'};
            app.dataFileUITable.RowName = {};
            app.dataFileUITable.CellEditCallback = createCallbackFcn(app, @dataFileUITableCellEdit, true);
            app.dataSelectedFilesLabel = uilabel(app.DataTab);
            app.dataSelectedFilesLabel.Text = {'Selected Files'; ''};
            app.dataAddtoTableButton = uibutton(app.DataTab, 'push');
            app.dataAddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @dataAddtoTableButtonPushed, true);
            app.dataAddtoTableButton.Text = 'Add to Table';
            app.dataClearCurrentButton = uibutton(app.DataTab, 'push');
            app.dataClearCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearCurrentButtonPushed, true);
            app.dataClearCurrentButton.Text = 'Clear Current';
            app.dataClearTableButton = uibutton(app.DataTab, 'push');
            app.dataClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearTableButtonPushed, true);
            app.dataClearTableButton.Text = 'Clear Table';
            app.dataMetadataUITable = uitable(app.DataTab);
            app.dataMetadataUITable.ColumnName = {'Data Type'; 'Value'};
            app.dataMetadataUITable.RowName = {};
            app.dataMetadataLabel = uilabel(app.DataTab);
            app.dataMetadataLabel.Text = 'Metadata';
            app.dataClearMetadataButton = uibutton(app.DataTab, 'push');
            app.dataClearMetadataButton.ButtonPushedFcn = createCallbackFcn(app, @dataClearMetadataButtonPushed, true);
            app.dataClearMetadataButton.Text = 'Clear Metadata';
            app.dataLaunchStackViewerButton = uibutton(app.DataTab, 'push');
            app.dataLaunchStackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @dataLaunchStackViewerButtonPushed, true);
            app.dataLaunchStackViewerButton.Text = 'Launch Stack Viewer';
            app.dataBackButton = uibutton(app.DataTab, 'push');
            app.dataBackButton.ButtonPushedFcn = createCallbackFcn(app, @dataBackButtonPushed, true);
            app.dataBackButton.Text = 'Back';
            app.dataNextButton = uibutton(app.DataTab, 'push');
            app.dataNextButton.ButtonPushedFcn = createCallbackFcn(app, @dataNextButtonPushed, true);
            app.dataNextButton.Text = 'Next';
            app.dataMessageLabel = uilabel(app.DataTab);
            app.dataMessageLabel.Text = '';
            app.dataRemoveSelectedButton = uibutton(app.DataTab, 'push');
            app.dataRemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @dataRemoveSelectedButtonPushed, true);
            app.dataRemoveSelectedButton.Text = 'Remove Selected';
            app.PrepTab = uitab(app.mainTabGroup);
            app.PrepTab.Title = 'Prep';
            app.prepTabGroup = uitabgroup(app.PrepTab);
            app.LoadTab = uitab(app.prepTabGroup);
            app.LoadTab.Title = 'Load';
            app.RegisterTab = uitab(app.prepTabGroup);
            app.RegisterTab.Title = 'Register';
            app.BleachTab = uitab(app.prepTabGroup);
            app.BleachTab.Title = 'Bleach';
            app.CropTab = uitab(app.prepTabGroup);
            app.CropTab.Title = 'Crop';
            app.BackgroundTab = uitab(app.prepTabGroup);
            app.BackgroundTab.Title = 'Background';
            app.ImmobileTab = uitab(app.prepTabGroup);
            app.ImmobileTab.Title = 'Immobile';
            app.FFTTab = uitab(app.prepTabGroup);
            app.FFTTab.Title = 'FFT';
            app.prepBackButton = uibutton(app.PrepTab, 'push');
            app.prepBackButton.ButtonPushedFcn = createCallbackFcn(app, @prepBackButtonPushed, true);
            app.prepBackButton.Text = 'Back';
            app.prepNextButton = uibutton(app.PrepTab, 'push');
            app.prepNextButton.ButtonPushedFcn = createCallbackFcn(app, @prepNextButtonPushed, true);
            app.prepNextButton.Text = 'Next';
            app.SetupTab = uitab(app.mainTabGroup);
            app.SetupTab.Title = 'Setup';
            app.setupUITable = uitable(app.SetupTab);
            app.setupUITable.ColumnName = {'Run'; 'Batch'; 'Protocol'; 'Channel 1'; 'Channel 2'; 'ROIsize'; 'ROIshift'; 'TOIsize'; 'TOIshift'; 'FitMode'; 'FitRadius'; 'FileSelected'};
            app.setupUITable.RowName = {};
            app.setupUITable.CellEditCallback = createCallbackFcn(app, @setupUITableCellEdit, true);
            app.ROISizeEditFieldLabel = uilabel(app.SetupTab);
            app.ROISizeEditFieldLabel.Text = 'ROI Size';
            app.setupROISizeEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupROISizeEditField.Limits = [1 Inf];
            app.setupROISizeEditField.Value = 1;
            app.ROIShiftEditFieldLabel = uilabel(app.SetupTab);
            app.ROIShiftEditFieldLabel.Text = 'ROI Shift';
            app.setupROIShiftEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupROIShiftEditField.Limits = [1 Inf];
            app.setupROIShiftEditField.Value = 1;
            app.TOISizeEditFieldLabel = uilabel(app.SetupTab);
            app.TOISizeEditFieldLabel.Text = 'TOI Size';
            app.setupTOISizeEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupTOISizeEditField.Limits = [1 Inf];
            app.setupTOISizeEditField.Value = 1;
            app.TOIShiftEditFieldLabel = uilabel(app.SetupTab);
            app.TOIShiftEditFieldLabel.Text = 'TOI Shift';
            app.setupTOIShiftEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupTOIShiftEditField.Limits = [1 Inf];
            app.setupTOIShiftEditField.Value = 1;
            app.FitModeDropDownLabel = uilabel(app.SetupTab);
            app.FitModeDropDownLabel.Text = 'Fit Mode';
            app.setupFitModeDropDown = uidropdown(app.SetupTab);
            app.FitRadiusEditFieldLabel = uilabel(app.SetupTab);
            app.FitRadiusEditFieldLabel.Text = 'Fit Radius';
            app.setupFitRadiusEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupFitRadiusEditField.Limits = [1 Inf];
            app.setupFitRadiusEditField.Value = 1;
            app.setupClearTableButton = uibutton(app.SetupTab, 'push');
            app.setupClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @setupClearTableButtonPushed, true);
            app.setupClearTableButton.Text = 'Clear Table';
            app.setupBackButton = uibutton(app.SetupTab, 'push');
            app.setupBackButton.ButtonPushedFcn = createCallbackFcn(app, @setupBackButtonPushed, true);
            app.setupBackButton.Text = 'Back';
            app.setupNextButton = uibutton(app.SetupTab, 'push');
            app.setupNextButton.ButtonPushedFcn = createCallbackFcn(app, @setupNextButtonPushed, true);
            app.setupNextButton.Text = 'Next';
            app.setupRemoveSelectedButton = uibutton(app.SetupTab, 'push');
            app.setupRemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @setupRemoveSelectedButtonPushed, true);
            app.setupRemoveSelectedButton.Text = 'Remove Selected';
            app.Channel1Label = uilabel(app.SetupTab);
            app.Channel1Label.Text = 'Channel 1';
            app.setupChannel1DropDown = uidropdown(app.SetupTab);
            app.setupChannel1DropDown.Items = {};
            app.setupChannel1DropDown.Value = {};
            app.Channel2Label = uilabel(app.SetupTab);
            app.Channel2Label.Text = 'Channel 2';
            app.setupChannel2DropDown = uidropdown(app.SetupTab);
            app.setupChannel2DropDown.Items = {};
            app.setupChannel2DropDown.Value = {};
            app.setupMessageLabel = uilabel(app.SetupTab);
            app.setupMessageLabel.Text = '';
            app.BatchEditFieldLabel = uilabel(app.SetupTab);
            app.BatchEditFieldLabel.Text = 'Batch';
            app.setupBatchEditField = uieditfield(app.SetupTab, 'numeric');
            app.setupBatchEditField.Limits = [1 Inf];
            app.setupBatchEditField.Value = 1;
            app.ProtocolDropDownLabel = uilabel(app.SetupTab);
            app.ProtocolDropDownLabel.Text = 'Protocol';
            app.setupProtocolDropDown = uidropdown(app.SetupTab);
            app.setupProtocolDropDown.Items = {'STICS', 'STICCS'};
            app.setupProtocolDropDown.Value = 'STICS';
            app.setupAddtoTableButton = uibutton(app.SetupTab, 'push');
            app.setupAddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @setupAddtoTableButtonPushed, true);
            app.setupAddtoTableButton.Text = 'Add to Table';
            app.setupLaunchStackViewerButton = uibutton(app.SetupTab, 'push');
            app.setupLaunchStackViewerButton.ButtonPushedFcn = createCallbackFcn(app, @setupLaunchStackViewerButtonPushed, true);
            app.setupLaunchStackViewerButton.Text = 'Launch Stack Viewer';
            app.RunTab = uitab(app.mainTabGroup);
            app.RunTab.Title = 'Run';
            app.runBackButton = uibutton(app.RunTab, 'push');
            app.runBackButton.ButtonPushedFcn = createCallbackFcn(app, @runBackButtonPushed, true);
            app.runBackButton.Text = 'Back';
            app.runNextButton = uibutton(app.RunTab, 'push');
            app.runNextButton.ButtonPushedFcn = createCallbackFcn(app, @runNextButtonPushed, true);
            app.runNextButton.Text = 'Next';
            app.UITable = uitable(app.RunTab);
            app.UITable.ColumnName = {'Run'; 'Batch'};
            app.UITable.RowName = {};
            app.runQueueLabel = uilabel(app.RunTab);
            app.runQueueLabel.Text = 'Queue';
            app.UITable_2 = uitable(app.RunTab);
            app.UITable_2.ColumnName = {'Run'; 'Batch'};
            app.UITable_2.RowName = {};
            app.runQueueLabel_2 = uilabel(app.RunTab);
            app.runQueueLabel_2.Text = 'Currently Running';
            app.UITable_3 = uitable(app.RunTab);
            app.UITable_3.ColumnName = {'Run'; 'Batch'};
            app.UITable_3.RowName = {};
            app.runQueueLabel_3 = uilabel(app.RunTab);
            app.runQueueLabel_3.Text = 'Finished';
            app.UITable_4 = uitable(app.RunTab);
            app.UITable_4.ColumnName = {'Run'; 'Batch'};
            app.UITable_4.RowName = {};
            app.ResultsTab = uitab(app.mainTabGroup);
            app.ResultsTab.Title = 'Results';
            app.resultsNextButton = uibutton(app.ResultsTab, 'push');
            app.resultsNextButton.ButtonPushedFcn = createCallbackFcn(app, @resultsNextButtonPushed, true);
            app.resultsNextButton.Text = 'Next';
            app.resultsBackButton = uibutton(app.ResultsTab, 'push');
            app.resultsBackButton.ButtonPushedFcn = createCallbackFcn(app, @resultsBackButtonPushed, true);
            app.resultsBackButton.Text = 'Back';
            app.AnalysisTab = uitab(app.mainTabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.analysisBackButton = uibutton(app.AnalysisTab, 'push');
            app.analysisBackButton.ButtonPushedFcn = createCallbackFcn(app, @analysisBackButtonPushed, true);
            app.analysisBackButton.Text = 'Back';
            app.analysisNextButton = uibutton(app.AnalysisTab, 'push');
            app.analysisNextButton.ButtonPushedFcn = createCallbackFcn(app, @analysisNextButtonPushed, true);
            app.analysisNextButton.Text = 'Next';
            app.ExportTab = uitab(app.mainTabGroup);
            app.ExportTab.Title = 'Export';
            app.exportBackButton = uibutton(app.ExportTab, 'push');
            app.exportBackButton.ButtonPushedFcn = createCallbackFcn(app, @exportBackButtonPushed, true);
            app.exportBackButton.Text = 'Back';
            app.exportDoneButton = uibutton(app.ExportTab, 'push');
            app.exportDoneButton.ButtonPushedFcn = createCallbackFcn(app, @exportDoneButtonPushed, true);
            app.exportDoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICSgui1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end